classdef CPSProject_App8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GoToMainButton         matlab.ui.control.Button
        Image3                 matlab.ui.control.Image
        YourShoppingCartLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
    end

    
    properties (Access = public)
        %Property % Description
        ShoppingCart = "";
        ShoppingCart2 = "";
        BrideCount = 0;
        BridesmaidCount = 0;
    end

    
    methods (Access = public, Static)
        
        function setBridesmaidShoppingCart(app8, cart)
           
            app8.ShoppingCart = cart;

            app8.Image.ImageSource = app8.ShoppingCart
        
       
        end
    end
    
    
    methods (Access = public, Static)
        
        function setBrideShoppingCart(app82, cart2)
           
            app82.ShoppingCart2 = cart2;
            app82.Image3.ImageSource = app82.ShoppingCart2
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoToMainButton
        function GoToMainButtonPushed(app, event)
            CPSProject_App1_2
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ShoppingCartImage.png');
            app.Image = uiimage(app.UIFigure);
            app.YourShoppingCartLabel = uilabel(app.UIFigure);
            app.YourShoppingCartLabel.Text = 'Your Shopping Cart';
            app.Image3 = uiimage(app.UIFigure);
            app.GoToMainButton = uibutton(app.UIFigure, 'push');
            app.GoToMainButton.ButtonPushedFcn = createCallbackFcn(app, @GoToMainButtonPushed, true);
            app.GoToMainButton.Text = 'Go To Main';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App8

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end